classdef Real_Time_FM_Demod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RealTimeFMSDRadiov16UIFigure  matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        PlayAudioButton               matlab.ui.control.Button
        RunSimulationButton           matlab.ui.control.Button
        CentreFreqSlider              matlab.ui.control.Slider
        SDRStatusTextArea             matlab.ui.control.TextArea
        RunSweepButton                matlab.ui.control.Button
        CentreFreqText                matlab.ui.control.TextArea
        CentreFrequencyLabel          matlab.ui.control.Label
        Introduction                  matlab.ui.control.TextArea
        ParametersLabel               matlab.ui.control.Label
        ModFFT                        matlab.ui.control.UIAxes
        DemodFFT                      matlab.ui.control.UIAxes
        PauseAudioButton              matlab.ui.control.Button
        StatusLabel                   matlab.ui.control.Label
        AnalysisLabel                 matlab.ui.control.Label
        SNRdBTextArea                 matlab.ui.control.TextArea
        ChannelPowerTextArea          matlab.ui.control.TextArea
        NoisePowerTextArea            matlab.ui.control.TextArea
        SNRBWSlider                   matlab.ui.control.Slider
        SNRBandwidthLabel             matlab.ui.control.Label
        SNRBandwidthText              matlab.ui.control.TextArea
        RunTimeTextArea               matlab.ui.control.TextArea
        RunTimesLabel                 matlab.ui.control.Label
        SNRdBLabel                    matlab.ui.control.Label
        SweepIntervalsLabel           matlab.ui.control.Label
        NoisedBLabel                  matlab.ui.control.Label
        ChanneldBLabel                matlab.ui.control.Label
        SweepIntervalField            matlab.ui.control.NumericEditField
        PotentialStations             matlab.ui.control.TextArea
        FMStationsLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
            % SDR Centre Frequency    
            fc = 104.9e6; 
            
            snr_bw = 200e3; % Initalised centre channel bandwidth for SNR
            fft_len = 2048; % Length of the mod/demod FFTs
            
            % Simulation Parameters       
            sim_time = inf;
            simulate = 0; % Initialise simulation to be off
            frame_length = 51200*4; % Each frame should ideally be multiple of 50 & 512
            
            % Sweep Algorithm Parameters
            sweep_interval = 6;
            sweep_str = "Running Sweep at";
            sweep = 0; % Initialise sweep to be off
            
            % SDR and audio sampling frequencies
            fs = 2.4e6;
            fs_audio = 48e3;
            
            % System object for physical rtl-sdr
            NESDR = comm.SDRRTLReceiver(...
                '0',...
                'CenterFrequency', 104.9e6,...
                'EnableTunerAGC', 1,...
                'SampleRate', 2.4e6, ...
                'SamplesPerFrame', 51200*4,...
                'OutputDataType', 'double');
            
            % Play the demodulated, decimated output to audio
            sound_card = audioDeviceWriter(48e3);
            
            % Audio Buffer
            buff = dsp.Buffer(4096);
            
            % Initialise audio playback to be off
            play_audio = 0; 
    end
 
    
    methods (Access = private)
        
        function [mean_np,mean_cp,station_in_centre_channel,station_count] = calc_SNR(app, FFT_power)  
            
               %%****************************SNR calculations****************************%%
                %How many bins in a frequency = (f*app.fft_len)/fs
                % Get half of FFT length
                half_len = app.fft_len/2;
                
                % Calculate FFT bins in 200kHz and half of channel (100kHz)
                standard_bins = round((200e3*app.fft_len)/app.fs);
                hundred_kilohertz_bins = round(standard_bins/2);
                
                % Calculate FFT bins in a 20kHz bandwidth
                twenty_k_bins = round((20e3*app.fft_len)/app.fs);

                % Calculate FFT bins of 50% of the current SNR bandwidth
                half_channel_bins = round((app.snr_bw*half_len)/app.fs);

                % Calculate bin range for SNR channel based on FFT length
                chan_start = half_len - half_channel_bins;
                chan_end = half_len + half_channel_bins;
                
                % Initalise station count
                station_count = 0;
                
                % Calculate the power in the SNR channel & its mean
                channel_power = FFT_power(chan_start:chan_end);            
                mean_cp = mean(channel_power);
                
                % Start channel search index at chan_start + 0.1 MHz for first potential station
                c_start = hundred_kilohertz_bins;
                
                % Identify if there is a station in each of the 12 channels
                %.................................................................................
                % If not a station in centre channel, calculate the noise power as the channel power,
                % Compare the channel power with the other non-station channel to find lowest mean
                %.................................................................................
                % If there is a station in centre channel, calculate the noise power as the adjacent channel power
                station_in_centre_channel = 0; 
                for i = 1:(app.fs/200e3)
                    
                    % End is 200kHz bins above start
                    c_end = c_start+standard_bins;
                    
                    % Display bin index of current channel fc
                    c_centre = round((c_end+c_start)/2);
                    
                    % Find max power in 200kHz channel band
                    outside_forty_power = mean(FFT_power([c_start:(c_centre-twenty_k_bins),(c_centre+twenty_k_bins):c_end-1]));
                    forty_k_range = (c_centre-twenty_k_bins:c_centre+twenty_k_bins);
                    forty_k_power = FFT_power(forty_k_range);
                    forty_k_mean = mean(forty_k_power);
                    
                    % Power difference of 40kHz around carrier compared to remaining 160kHz
                    channel_mean_diff = forty_k_mean - outside_forty_power;
                     
                    % Test if power difference shows there's a station in channel
                    if abs(channel_mean_diff) > 1.5
                        station_in_channel = 1;
                        % Flag for if centre freq is FM station
                        if abs(c_centre - half_len) < 20
                            station_in_centre_channel = 1; 
                        end
                    else 
                        station_in_channel = 0; 
                    end
                    
                    % Initialise 1st channel as lowest mean, then test each channel for if lower.                 
                    if i == 1
                        lowest_mean = outside_forty_power;
                    end
                    if i < 1 && twohundred_k_mean < lowest_mean 
                        lowest_mean = outside_forty_power;
                    end 

                    station_count = station_count + station_in_channel;
                    
                    % Increment the start frequency bin by equivalent of 200kHz
                    if c_start < app.fft_len - (standard_bins*2)
                        c_start = c_start + (standard_bins);
                    else
                        break;
                    end
                end
                % Output the noise power as the lowest mean calculated of the 12 channels
                mean_np = lowest_mean;
        end
    
        function freq = bin_to_freq(app,bin)
            bin_diff =  bin - app.fft_len/2;
            freq_diff = (bin_diff * app.fs)/app.fft_len;
            freq = app.fc + freq_diff;
        end
    
        function bins = freq_to_bins(app, freq)
            bins = round((freq*app.fft_len)/app.fs);
        end
        
        function change_freq_axis(app, xmin, xmax)
            app.ModFFT.XLim = [xmin xmax]; 
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set_param(0, 'GlobalUseClassicAccelMode', 'on');
            if app.NESDR.EnableTunerAGC
                agc_str = "Enabled";
            else
                agc_str = "Disabled";
            end
            app.Introduction.Value = sprintf(["--------- Please connect a RTL-SDR device via USB. --------- \n\n >>>> RTL-SDR Sampling Rate:  '%.1fMHz' \n >>>> Fast Fourier Transform Length: '%d' \n >>>> RTL-SDR Automatic Gain Control is: '%s'"], (app.fs/1e6), app.fft_len, agc_str);
        end

        % Callback function
        function ModFFTButtonPushed(app, event)
            app.ModFFT.Visible = 'on';
        end

        % Callback function
        function DemodFFTButtonPushed(app, event)
            app.DemodFFT.Visible = 'on';
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            app.play_audio = 1; 
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.simulate = ~app.simulate;
            
            if isempty(sdrinfo(app.NESDR.RadioAddress))
                app.SDRStatusTextArea.Value = 'Not detected a compatible RTL-SDR device!';
                error('Not detected a compatible RTL-SDR device!');
            else 
                if app.NESDR.EnableTunerAGC
                    agc_str = "Enabled";
                else
                    agc_str = "Disabled";
                end
            app.Introduction.Value = sprintf(["--------- Detected a RTL-SDR device at USB%s. --------- \n\n >>>> RTL-SDR Sampling Rate:  '%.1fMHz' \n >>>> Fast Fourier Transform Length: '%d' \n >>>> RTL-SDR Automatic Gain Control is: '%s'"],app.NESDR.RadioAddress, (app.fs/1e6), app.fft_len, agc_str);
                app.SDRStatusTextArea.Value = sprintf("Simulating!");
            end
            
            
            % Initialising FIR de-emphasis filter
            de_cutoff = 3.183e3; % EU de-emphasis cutoff freq
            [num, den] = butter(1,de_cutoff/(app.fs_audio/2));
            
            % Delay block
            delay = dsp.Delay;
            
            % FIR Decimator Block (factor of 10: 2.4MHz to 240KHz)
            dec_10 = dsp.FIRDecimator(...
                'DecimationFactor', 10,...
                'Numerator', firpm(100,[2 120e3 240e3 (2.4e6/2)]/(2.4e6/2),[1 1 0 0], [1 1], 20));
            
            % FIR Decimator Block (factor of 5 240kHz to 48kHz)
            dec_5 = dsp.FIRDecimator(...
                'DecimationFactor', 5,...
                'Numerator', firpm(100,[0 15e3 20e3 (240e3/2)]/(240e3/2), [1 1 0 0], [1 1], 20));
            
            %% De-emphasis Filter Calculations
            
            N1   = 4;        % FIR filter order
            Rp1  = 0.00057565; % Corresponds to 0.01 dB peak-to-peak ripple
            Rst1 = 0.5;       % Corresponds to 6 dB stopband attenuation
            
            % TODO: Hann window to be applied to FFT
%             window = dsp.Window( ...
%             'WindowFunction','Hann', ...
%             'WeightsOutputPort',true);
            
            deemph_num = firceqrip(N1,de_cutoff/(app.fs_audio/2),[Rp1 Rst1],'passedge'); % eqnum = vec of coeffs
            %fvtool(deemph_num,'Fs',fs_audio,'Color','White') % Visualize filter
            deemph_filter = dsp.FIRFilter('Numerator',deemph_num);
            
            %% FM Low-pass Filter Calculations
            N2   = 10;        % FIR filter order
            lpf_cutoff = 15e3; % FM useful frequency cutoff
            Rp2  = 0.00057565; % Corresponds to 0.01 dB peak-to-peak ripple
            Rst2 = 1e-4;       % Corresponds to 80 dB stopband attenuation
            
 
            lpf_num = firceqrip(N2,lpf_cutoff/(app.fs_audio/2),[Rp1 Rst1],'passedge'); % eqnum = vec of coeffs
            lpf_filter = dsp.FIRFilter('Numerator',lpf_num);
            
            % Plot object definition and link to mod/demod FFT axes
            mod_plot = plot(app.ModFFT,0,0);
            demod_plot = plot(app.DemodFFT,0,0);
            % Plot handles for more efficient X and Y data updating
            mp_handle = handle(mod_plot);
            dmp_handle = handle(demod_plot);
            
            % Simulation paramter definitons
            loop_count = 0;
            run_time = 0; 
            frame_time = app.frame_length/app.fs; % The time taken for frame is 204800/2.4e6 = 85.3ms
            
            % Average powers matrix pre-allocation for effiecient memory usage for SNR calculation
            av_cp = zeros(1,6);
            av_np = zeros(1,6);
            av_station = zeros(1,6);
            av_count = zeros(1,6);
            
            % Main simulation loop, protected by error handling
%             try 
                while (run_time < app.sim_time) && app.simulate == 1
                    % fetch a frame from obj_rtlsdr (live or offline)
                    rx_data = app.NESDR();
                    
                    % ********************** WIDEBAND RECEIVE SIGNAL FFT *************************          
                    radio_fft = abs(fft(rx_data, app.fft_len)/app.fft_len);
                    ordered_fft = 2*abs([(radio_fft(app.fft_len/2+1:end)).' (radio_fft(1:app.fft_len/2)).']);
                    frequency =  app.fs/2*linspace(-1,1,app.fft_len);
                    power = 10*log10(ordered_fft);
                    % ****************************************************************************
                    
                    
                    % Calculate the channel & noise power (& SNR) every loop
                    [np, channel_peak, centre_station, station_count] = calc_SNR(app,power);
                    av_np = [av_np, np];
                    av_cp = [av_cp, channel_peak];
                    av_station = [av_station, centre_station];
                    av_count = [av_count, station_count];
                    
                    % Set axis and draw radio signal FFT
                    mp_handle.XData = (frequency+app.fc)/1e6.';
                    mp_handle.YData = power;
                    
                    % Perform FM band sweep  
                    % Increment the centre frequency by (fs/2)Hz every sweep interval 
                    if ~mod(loop_count, round(app.sweep_interval/frame_time)) && app.sweep == 1 && (app.fc < ( 107.5e6 - app.fs/2) + 1)
                        nyq = app.fs/2; 
                        app.fc = app.fc + nyq;
                        format_freq = app.fc/1e6;
                        change_freq_axis(app, (app.fc-(nyq))/1e6, (app.fc+(nyq))/1e6 );
                        app.NESDR.CenterFrequency = app.fc;
                        app.CentreFreqSlider.Value = (format_freq);
                        app.CentreFreqText.Value = sprintf('%.1f MHz',(format_freq));
                    end
                    
                    % Decimate data sampling rate from 2.4 MHz to 240kHz
                    decimated_data = step(dec_10,rx_data);
                       
                    % Implement Delay Line Frequency Discriminator
                    delay_block = step(delay, decimated_data);
                    conjugate = conj(decimated_data);
                    product = delay_block.*conjugate;
                    angle_block = angle(product);
                    
                    % Decimate + De-emphasis filter data
                    demod_data = step(dec_5,angle_block);
                    deemph_data = step(deemph_filter,demod_data);
                    final_data = step(lpf_filter, deemph_data);
                    
                    
                    % ********************** AUDIO SIGNAL FFT *************************
                    audio_fft = abs(fft(final_data, app.fft_len)/app.fft_len);
                    frequency1 = app.fs_audio/2*linspace(-1,1,app.fft_len);
                    power1 = 10*log10(2*abs([(audio_fft(app.fft_len/2+1:end)).' (audio_fft(1:app.fft_len/2)).']));
                    % *****************************************************************   
                
                    % Draw audio FFT
                    dmp_handle.XData = (frequency1)/1e3.';
                    dmp_handle.YData = power1;
                   
                    % output demodulated signal to speakers
                    audio_data = app.buff(final_data); 
                    if app.play_audio == 1; step(app.sound_card, audio_data); end
                   
                    % update run_time after processing another frame
                    run_time = run_time + frame_time;
                    % Every sixth loop, print the SNR and channel/noise powers averaged over 5 loops 
                    if  ~mod(loop_count, 6)
                        mcp = mean(av_cp);
                        mnp = mean(av_np);
                        mst = mean(av_station);
                        mcn = round(mean(av_count));
                        snr = mcp-mnp;
                        output_data = [app.fc snr run_time];
                        dlmwrite("snr_log.csv", output_data,'-append');
                        if mst
                            app.SDRStatusTextArea.Value = sprintf("There is a station at %.1f MHz", app.fc/1e6);
                        else
                            app.SDRStatusTextArea.Value = sprintf("No station detected at %.1f MHz", app.fc/1e6);
                        end
                        app.PotentialStations.Value = sprintf("%d", mcn);
                        app.SNRdBTextArea.Value = sprintf("%2.3f", mcp-mnp);
                        app.ChannelPowerTextArea.Value = sprintf("%2.3f",mcp);
                        app.NoisePowerTextArea.Value = sprintf("%2.3f",mnp);
                        app.RunTimeTextArea.Value =  sprintf("%2.2f", run_time);
                        % Empty the array - 
                        av_cp = [];
                        av_np = [];
                        av_station = [];
                        av_count = [];
                    end
                    
                    loop_count = loop_count + 1;
                    drawnow limitrate;
                    
                end
                
%             catch
%                 error("There was some kind of error.");
%                 return
%             
            %end   
        end

        % Value changing function: CentreFreqSlider
        function CentreFreqSliderValueChanging(app, event)
            changingValue = event.Value;
            sdr_freq = round(changingValue*1e6,-5);
            app.fc = sdr_freq;
            change_freq_axis(app, (app.fc-(app.fs/2))/1e6, (app.fc+(app.fs/2))/1e6 );
            app.NESDR.CenterFrequency = sdr_freq;
            app.CentreFreqText.Value = sprintf('%.1f MHz',(sdr_freq/1e6));
        end

        % Button pushed function: PauseAudioButton
        function PauseAudioButtonPushed(app, event)
            app.play_audio = 0; 
        end

        % Button pushed function: RunSweepButton
        function RunSweepButtonPushed(app, event)
            
            app.sweep = ~app.sweep;
            if app.sweep
                app.fc = 88.5e6;
                app.NESDR.CenterFrequency = app.fc; 
                change_freq_axis(app, (app.fc-(app.fs/2))/1e6, (app.fc+(app.fs/2))/1e6 );
                app.CentreFreqSlider.Value = (app.fc/1e6);
            end
            
            if (app.SweepIntervalField.Value)
                app.sweep_interval = round(app.SweepIntervalField.Value);
            else
                app.sweep_interval = 10;
                app.SweepIntervalField.Value = app.sweep_interval;
            end    
            app.SDRStatusTextArea.Value = sprintf("%s %d kHz/s.",app.sweep_str,(app.fs/1e3)/app.sweep_interval);
            
        end

        % Close request function: RealTimeFMSDRadiov16UIFigure
        function RealTimeFMSDRadiov16UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: SNRBWSlider
        function SNRBWSliderValueChanged(app, event)
            value = app.SNRBWSlider.Value;
            app.snr_bw = round(value,-1) * 1e3;
            app.SNRBandwidthText.Value = sprintf("%d kHz", app.snr_bw/1e3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RealTimeFMSDRadiov16UIFigure = uifigure;
            app.RealTimeFMSDRadiov16UIFigure.Name = 'Real Time FM SDRadio v1.6';
            app.RealTimeFMSDRadiov16UIFigure.CloseRequestFcn = createCallbackFcn(app, @RealTimeFMSDRadiov16UIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.TextArea.Value = {'Real Time FM SDRadio v1.6'};
            app.PlayAudioButton = uibutton(app.RealTimeFMSDRadiov16UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.RunSimulationButton = uibutton(app.RealTimeFMSDRadiov16UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.CentreFreqSlider = uislider(app.RealTimeFMSDRadiov16UIFigure);
            app.CentreFreqSlider.Limits = [88.5 107.5];
            app.CentreFreqSlider.MajorTicks = [88.5 90 91.5 93 94.5 96 97.5 99 100.5 102 103.5 105 106.5];
            app.CentreFreqSlider.ValueChangingFcn = createCallbackFcn(app, @CentreFreqSliderValueChanging, true);
            app.CentreFreqSlider.Value = 104.9;
            app.SDRStatusTextArea = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.RunSweepButton = uibutton(app.RealTimeFMSDRadiov16UIFigure, 'push');
            app.RunSweepButton.ButtonPushedFcn = createCallbackFcn(app, @RunSweepButtonPushed, true);
            app.RunSweepButton.Text = 'Run Sweep';
            app.CentreFreqText = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.CentreFreqText.Value = {'104.9 MHz'};
            app.CentreFrequencyLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.CentreFrequencyLabel.Text = 'Centre Frequency ';
            app.Introduction = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.ParametersLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.ModFFT = uiaxes(app.RealTimeFMSDRadiov16UIFigure);
            title(app.ModFFT, 'FM Band Section (fs=2.4MHz)')
            xlabel(app.ModFFT, 'Frequency (MHz)')
            ylabel(app.ModFFT, 'Power (dB)')
            app.ModFFT.XLim = [103.7 106.1];
            app.ModFFT.YLim = [-40 0];
            app.ModFFT.YTick = [-40 -20 0];
            app.ModFFT.YTickLabel = {'-40'; '-20'; '0'};
            app.DemodFFT = uiaxes(app.RealTimeFMSDRadiov16UIFigure);
            title(app.DemodFFT, 'FFT of demodulated signal (fs=48kHz)')
            xlabel(app.DemodFFT, 'Frequency (kHz)')
            ylabel(app.DemodFFT, 'Power (dB)')
            app.DemodFFT.XLim = [-24 24];
            app.DemodFFT.YLim = [-60 0];
            app.DemodFFT.XTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.DemodFFT.YTick = [-50 -25 0];
            app.PauseAudioButton = uibutton(app.RealTimeFMSDRadiov16UIFigure, 'push');
            app.PauseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAudioButtonPushed, true);
            app.PauseAudioButton.Text = 'Pause Audio';
            app.StatusLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.StatusLabel.Text = ' Status';
            app.AnalysisLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.SNRdBTextArea = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.ChannelPowerTextArea = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.NoisePowerTextArea = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.SNRBWSlider = uislider(app.RealTimeFMSDRadiov16UIFigure);
            app.SNRBWSlider.Limits = [10 200];
            app.SNRBWSlider.MajorTicks = [10 50 100 150 200];
            app.SNRBWSlider.ValueChangedFcn = createCallbackFcn(app, @SNRBWSliderValueChanged, true);
            app.SNRBWSlider.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.SNRBWSlider.Value = 200;
            app.SNRBandwidthLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.SNRBandwidthLabel.Text = 'SNR Bandwidth ';
            app.SNRBandwidthText = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.SNRBandwidthText.Value = {'200 kHz'};
            app.RunTimeTextArea = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.RunTimeTextArea.Editable = 'off';
            app.RunTimesLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.RunTimesLabel.Text = 'Run Time (s)';
            app.SNRdBLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.SNRdBLabel.Text = 'SNR (dB)';
            app.SweepIntervalsLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.SweepIntervalsLabel.Text = 'Sweep Interval (s)';
            app.NoisedBLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.NoisedBLabel.Text = ' Noise (dB)';
            app.ChanneldBLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.ChanneldBLabel.Text = ' Channel (dB)';
            app.SweepIntervalField = uieditfield(app.RealTimeFMSDRadiov16UIFigure, 'numeric');
            app.PotentialStations = uitextarea(app.RealTimeFMSDRadiov16UIFigure);
            app.FMStationsLabel = uilabel(app.RealTimeFMSDRadiov16UIFigure);
            app.FMStationsLabel.Text = 'FM Stations ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Real_Time_FM_Demod

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RealTimeFMSDRadiov16UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RealTimeFMSDRadiov16UIFigure)
        end
    end
end